classdef SleepSpindleDetector_Results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SleepSpindleDetectorResultsUIFigure  matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        SaveasMenu                 matlab.ui.container.Menu
        AllMenu                    matlab.ui.container.Menu
        SummaryMenu                matlab.ui.container.Menu
        EpochSummaryMenu           matlab.ui.container.Menu
        DurationsMenu              matlab.ui.container.Menu
        SpecificsMenu              matlab.ui.container.Menu
        PrimaryMenu                matlab.ui.container.Menu
        SecondaryMenu              matlab.ui.container.Menu
        BinaryClassificationsMenu  matlab.ui.container.Menu
        GroundTruthMenu            matlab.ui.container.Menu
        StatsMenu                  matlab.ui.container.Menu
        LoadMenu                   matlab.ui.container.Menu
        GroundTruthMenu_2          matlab.ui.container.Menu
        Menu                       matlab.ui.container.Menu
        FileMenu_2                 matlab.ui.container.Menu
        SaveasMenu_2               matlab.ui.container.Menu
        PrimaryMenu_2              matlab.ui.container.Menu
        SecondaryMenu_2            matlab.ui.container.Menu
        FileMenu_3                 matlab.ui.container.Menu
        SaveasMenu_3               matlab.ui.container.Menu
        StatsMenu_2                matlab.ui.container.Menu
        CalculateMenu              matlab.ui.container.Menu
        TabGroup                   matlab.ui.container.TabGroup
        SummaryTab                 matlab.ui.container.Tab
        SummaryLabel               matlab.ui.control.Label
        EpochSummaryUITable        matlab.ui.control.Table
        SecondaryPanel             matlab.ui.container.Panel
        SecondaryUITable           matlab.ui.control.Table
        PrimaryPanel               matlab.ui.container.Panel
        PrimaryUITable             matlab.ui.control.Table
        SleepSleepSpecificsTab     matlab.ui.container.Tab
        TabGroup2                  matlab.ui.container.TabGroup
        PrimaryClassificationTab   matlab.ui.container.Tab
        PrimarySpecifics           matlab.ui.control.Table
        SecondaryClassifcationTab  matlab.ui.container.Tab
        SecondarySpecifics         matlab.ui.control.Table
        ValidationTab              matlab.ui.container.Tab
        ProgressTextLabel          matlab.ui.control.Label
        GraphPredictionsButton     matlab.ui.control.Button
        PlotGroundTruthButton      matlab.ui.control.Button
        LoadGroundTruthButton      matlab.ui.control.Button
        GroundTruthUITable         matlab.ui.control.Table
        CaculateButton             matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        BinaryUITable              matlab.ui.control.Table
        UIAxes                     matlab.ui.control.UIAxes
        Tab                        matlab.ui.container.Tab
    end

    
    properties (Access = public)
        Callingapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
%             app.SleepSpindleDetectorResultsUIFigure.Visible = "off";
            app.Callingapp = MainApp;

            app.SleepSpindleDetectorResultsUIFigure.Units = 'normalized';
            app.SleepSpindleDetectorResultsUIFigure.Position(1) = 0.3;
            app.SleepSpindleDetectorResultsUIFigure.Position(2) = 0.3;
            app.SleepSpindleDetectorResultsUIFigure.Units = 'pixel';
        end

        % Button pushed function: LoadGroundTruthButton
        function LoadGroundTruthButtonPushed(app, event)
            filename = uigetfile;
            data = load(filename);

            if size(data.GroundTruth,2)>size(data.GroundTruth,1)
                data.GroundTruth = data.GroundTruth';
            end
            app.GroundTruthUITable.Data = double(data.GroundTruth);
            
            app.PlotGroundTruthButton.Enable = "on";

            figure(app.SleepSpindleDetectorResultsUIFigure)
        end

        % Button pushed function: CaculateButton
        function CaculateButtonPushed(app, event)
%             bar(app.UIAxes,app.Callingapp.Primary_SS)


            [TP,TN,FP,FN,Sensitivity,Specificity,Accuracy,MCC,Fscore] = Tracking_stats(app.Callingapp.Primary_SS,app.GroundTruthUITable.Data);
            [TP2,TN2,FP2,FN2,Sensitivity2,Specificity2,Accuracy2,MCC2,Fscore2] = Tracking_stats(app.Callingapp.Secondary_SS,app.GroundTruthUITable.Data);

            t = table;
            t.Methods = ["Primary";"Secondary"];
            t.TP = [TP;TP2];
            t.TN = [TN;TN2];
            t.FP = [FP;FP2];
            t.FN = [FN;FN2];
            t.Sensitivity = [Sensitivity;Sensitivity2];
            t.Specificity = [Specificity;Specificity2];
            t.Accuracy = [Accuracy;Accuracy2];
            t.MCC = [MCC;MCC2];
            t.Fscore = [Fscore;Fscore2];

            set(app.UITable,"Data",t);
        end

        % Button pushed function: PlotGroundTruthButton
        function PlotGroundTruthButtonPushed(app, event)
            if ~isempty(app.GroundTruthUITable.Data)
            bar(app.UIAxes,app.GroundTruthUITable.Data)
            hold(app.UIAxes,"on")
            end
        end

        % Menu selected function: AllMenu
        function AllMenuSelected(app, event)
            [folder,filename] = uiputfile(fullfile(pwd,".csv"));
            writetable(app.UITable.Data,fullfile(folder,filename))
            
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SleepSpindleDetectorResultsUIFigure = uifigure('Visible', 'off');
            app.SleepSpindleDetectorResultsUIFigure.Name = 'Sleep Spindle Detector: Results';
            app.SleepSpindleDetectorResultsUIFigure.Icon = 'SleepSpindleIcon.PNG';
            app.FileMenu = uimenu(app.SleepSpindleDetectorResultsUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.Text = 'Save as...';
            app.AllMenu = uimenu(app.SaveasMenu);
            app.AllMenu.MenuSelectedFcn = createCallbackFcn(app, @AllMenuSelected, true);
            app.AllMenu.Text = 'All';
            app.SummaryMenu = uimenu(app.SaveasMenu);
            app.SummaryMenu.Text = 'Summary';
            app.EpochSummaryMenu = uimenu(app.SummaryMenu);
            app.EpochSummaryMenu.Text = 'Epoch Summary';
            app.DurationsMenu = uimenu(app.SummaryMenu);
            app.DurationsMenu.Text = 'Durations';
            app.SpecificsMenu = uimenu(app.SaveasMenu);
            app.SpecificsMenu.Text = 'Specifics';
            app.PrimaryMenu = uimenu(app.SpecificsMenu);
            app.PrimaryMenu.Text = 'Primary';
            app.SecondaryMenu = uimenu(app.SpecificsMenu);
            app.SecondaryMenu.Text = 'Secondary';
            app.BinaryClassificationsMenu = uimenu(app.SaveasMenu);
            app.BinaryClassificationsMenu.Text = 'Binary Classifications';
            app.GroundTruthMenu = uimenu(app.SaveasMenu);
            app.GroundTruthMenu.Text = 'GroundTruth';
            app.StatsMenu = uimenu(app.SaveasMenu);
            app.StatsMenu.Text = 'Stats';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Text = 'Load';
            app.GroundTruthMenu_2 = uimenu(app.LoadMenu);
            app.GroundTruthMenu_2.Text = 'Ground Truth';
            app.Menu = uimenu(app.SleepSpindleDetectorResultsUIFigure);
            app.FileMenu_2 = uimenu(app.SleepSpindleDetectorResultsUIFigure);
            app.FileMenu_2.Visible = 'off';
            app.FileMenu_2.Text = 'File';
            app.SaveasMenu_2 = uimenu(app.FileMenu_2);
            app.SaveasMenu_2.Text = 'Save as..';
            app.PrimaryMenu_2 = uimenu(app.SaveasMenu_2);
            app.PrimaryMenu_2.Text = 'Primary';
            app.SecondaryMenu_2 = uimenu(app.SaveasMenu_2);
            app.SecondaryMenu_2.Text = 'Secondary';
            app.FileMenu_3 = uimenu(app.SleepSpindleDetectorResultsUIFigure);
            app.FileMenu_3.Visible = 'off';
            app.FileMenu_3.Text = 'File';
            app.SaveasMenu_3 = uimenu(app.FileMenu_3);
            app.SaveasMenu_3.Text = 'Save as...';
            app.StatsMenu_2 = uimenu(app.SleepSpindleDetectorResultsUIFigure);
            app.StatsMenu_2.Visible = 'off';
            app.StatsMenu_2.Text = 'Stats';
            app.CalculateMenu = uimenu(app.StatsMenu_2);
            app.CalculateMenu.Text = 'Calculate';
            app.TabGroup = uitabgroup(app.SleepSpindleDetectorResultsUIFigure);
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.PrimaryPanel = uipanel(app.SummaryTab);
            app.PrimaryPanel.Title = 'Primary';
            app.PrimaryUITable = uitable(app.PrimaryPanel);
            app.PrimaryUITable.ColumnName = {'mean duration (s)'; 'std duration (s)'; 'median duration (s)'};
            app.PrimaryUITable.RowName = {};
            app.SecondaryPanel = uipanel(app.SummaryTab);
            app.SecondaryPanel.Title = 'Secondary';
            app.SecondaryUITable = uitable(app.SecondaryPanel);
            app.SecondaryUITable.ColumnName = {'mean duration (s)'; 'std duration (s)'; 'median duration (s)'};
            app.SecondaryUITable.RowName = {};
            app.EpochSummaryUITable = uitable(app.SummaryTab);
            app.EpochSummaryUITable.ColumnName = {'Epoch'; 'SpindleCount_primary'; 'SpindleCount_secondary'; 'Spindle_percentage'; 'Spindle_percentage_secondary'; 'Sleep_percentage'; 'Sleep_percentage_secondary'};
            app.EpochSummaryUITable.RowName = {};
            app.SummaryLabel = uilabel(app.SummaryTab);
            app.SummaryLabel.Text = 'Summary';
            app.SleepSleepSpecificsTab = uitab(app.TabGroup);
            app.SleepSleepSpecificsTab.Title = 'Sleep Sleep Specifics';
            app.TabGroup2 = uitabgroup(app.SleepSleepSpecificsTab);
            app.PrimaryClassificationTab = uitab(app.TabGroup2);
            app.PrimaryClassificationTab.Title = 'Primary Classification';
            app.PrimarySpecifics = uitable(app.PrimaryClassificationTab);
            app.PrimarySpecifics.ColumnName = {'Timestamp Type'; 'Epoch'; 'Epochtime'; 'Time'; 'Duration (s)'; 'Max Amplitude'; 'Mean Amplitude'; 'Median Amplitude'};
            app.PrimarySpecifics.RowName = {};
            app.SecondaryClassifcationTab = uitab(app.TabGroup2);
            app.SecondaryClassifcationTab.Title = 'Secondary Classifcation';
            app.SecondarySpecifics = uitable(app.SecondaryClassifcationTab);
            app.SecondarySpecifics.ColumnName = {'Timestamp Type'; 'Epoch'; 'Epochtime'; 'Time'; 'Duration (s)'; 'Max Amplitude'; 'Mean Amplitude'; 'Median Amplitude'};
            app.SecondarySpecifics.RowName = {};
            app.ValidationTab = uitab(app.TabGroup);
            app.ValidationTab.Title = 'Validation';
            app.UIAxes = uiaxes(app.ValidationTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            zlabel(app.UIAxes, 'Z')
            app.BinaryUITable = uitable(app.ValidationTab);
            app.BinaryUITable.ColumnName = {'Primary'; 'Secondary'};
            app.BinaryUITable.RowName = {};
            app.UITable = uitable(app.ValidationTab);
            app.UITable.ColumnName = {'TP'; 'TN'; 'FP'; 'FN'; 'Sensitivity'; 'Specificity'; 'Accuracy'; 'MCC'; 'F-score'};
            app.UITable.RowName = {};
            app.CaculateButton = uibutton(app.ValidationTab, 'push');
            app.CaculateButton.ButtonPushedFcn = createCallbackFcn(app, @CaculateButtonPushed, true);
            app.CaculateButton.Text = 'Caculate';
            app.GroundTruthUITable = uitable(app.ValidationTab);
            app.GroundTruthUITable.ColumnName = {'Ground Truth'};
            app.GroundTruthUITable.RowName = {};
            app.LoadGroundTruthButton = uibutton(app.ValidationTab, 'push');
            app.LoadGroundTruthButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGroundTruthButtonPushed, true);
            app.LoadGroundTruthButton.Text = 'Load Ground Truth';
            app.PlotGroundTruthButton = uibutton(app.ValidationTab, 'push');
            app.PlotGroundTruthButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGroundTruthButtonPushed, true);
            app.PlotGroundTruthButton.Enable = 'off';
            app.PlotGroundTruthButton.Text = 'Plot Ground Truth';
            app.GraphPredictionsButton = uibutton(app.ValidationTab, 'push');
            app.GraphPredictionsButton.Text = 'Graph Predictions';
            app.ProgressTextLabel = uilabel(app.ValidationTab);
            app.ProgressTextLabel.Visible = 'off';
            app.ProgressTextLabel.Text = 'ProgressText';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            % Show the figure after all components are created
            app.SleepSpindleDetectorResultsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SleepSpindleDetector_Results(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SleepSpindleDetectorResultsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SleepSpindleDetectorResultsUIFigure)
        end
    end
end