classdef VideoCropper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CropImageUIFigure  matlab.ui.Figure
        FileMenu           matlab.ui.container.Menu
        CropMenu           matlab.ui.container.Menu
        Toolbar            matlab.ui.container.Toolbar
        PushTool           matlab.ui.container.toolbar.PushTool
        UIAxes             matlab.ui.control.UIAxes
    end


    properties (Access = public)
        CallingApp
        roi
    end
    
    methods (Access = public)
        
        function roi_listener(app)
            app.roi.Position = round(app.roi.Position);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
           % Store main app object
            app.CallingApp = mainapp;
           
            app.CropImageUIFigure.Visible = 'off';
            app.CropImageUIFigure.Units = 'normalized';
            app.CropImageUIFigure.Position(1) = 0.35;%[0.35 0.35 0.30 0.30];
            app.CropImageUIFigure.Position(2) = 0.35;
            app.CropImageUIFigure.Units = 'pixel';
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            app.CropMenu.Enable = 'on';

            app.roi = drawrectangle(app.UIAxes);
            app.roi.Position = round(app.roi.Position);

            addlistener(app.roi,'ROIMoved', @roi_listener);
        end

        % Menu selected function: CropMenu
        function CropMenuSelected(app, event)
            app.CallingApp.roi = app.roi;
            
            %Previous Axes
            
            if size(app.CallingApp.Video_Window.PrevSec_ax.Children.CData) < 3
                app.CallingApp.Video_Window.PrevSec_ax.Children.CData = app.CallingApp.Video_Window.PrevSec_ax.Children.CData(app.roi.Position(2):app.roi.Position(2)+app.roi.Position(4)-1,app.roi.Position(1):app.roi.Position(1)+app.roi.Position(3)-1);
            else
                app.CallingApp.Video_Window.PrevSec_ax.Children.CData = app.CallingApp.Video_Window.PrevSec_ax.Children.CData(app.roi.Position(2):app.roi.Position(2)+app.roi.Position(4)-1,app.roi.Position(1):app.roi.Position(1)+app.roi.Position(3)-1,:);
            end
            
            %Current Axes
            
            if size(app.CallingApp.Video_Window.CurSec_ax.Children.CData) < 3
                app.CallingApp.Video_Window.CurSec_ax.Children.CData = app.CallingApp.Video_Window.CurSec_ax.Children.CData(app.roi.Position(2):app.roi.Position(2)+app.roi.Position(4)-1,app.roi.Position(1):app.roi.Position(1)+app.roi.Position(3)-1);
            else
                app.CallingApp.Video_Window.CurSec_ax.Children.CData = app.CallingApp.Video_Window.CurSec_ax.Children.CData(app.roi.Position(2):app.roi.Position(2)+app.roi.Position(4)-1,app.roi.Position(1):app.roi.Position(1)+app.roi.Position(3)-1,:);
            end
            
            %Difference Axes
            
            if size(app.CallingApp.Video_Window.Difference_ax.Children.CData) < 3
                app.CallingApp.Video_Window.Difference_ax.Children.CData = app.CallingApp.Video_Window.Difference_ax.Children.CData(app.roi.Position(2):app.roi.Position(2)+app.roi.Position(4)-1,app.roi.Position(1):app.roi.Position(1)+app.roi.Position(3)-1);
            else
                app.CallingApp.Video_Window.Difference_ax.Children.CData = app.CallingApp.Video_Window.Difference_ax.Children.CData(app.roi.Position(2):app.roi.Position(2)+app.roi.Position(4)-1,app.roi.Position(1):app.roi.Position(1)+app.roi.Position(3)-1,:);
            end
            
            %Current Axes
            
            if size(app.CallingApp.Video_Window.Thresholded_ax.Children.CData) < 3
                app.CallingApp.Video_Window.Thresholded_ax.Children.CData = app.CallingApp.Video_Window.Thresholded_ax.Children.CData(app.roi.Position(2):app.roi.Position(2)+app.roi.Position(4)-1,app.roi.Position(1):app.roi.Position(1)+app.roi.Position(3)-1);
            else
                app.CallingApp.Video_Window.Thresholded_ax.Children.CData = app.CallingApp.Video_Window.Thresholded_ax.Children.CData(app.roi.Position(2):app.roi.Position(2)+app.roi.Position(4)-1,app.roi.Position(1):app.roi.Position(1)+app.roi.Position(3)-1,:);
            end
            
            app.CropImageUIFigure.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CropImageUIFigure = uifigure('Visible', 'off');
            app.CropImageUIFigure.Name = 'Crop Image';
            app.CropImageUIFigure.Icon = 'SleepSpindleIcon.PNG';
            app.FileMenu = uimenu(app.CropImageUIFigure);
            app.FileMenu.Text = 'File';
            app.CropMenu = uimenu(app.FileMenu);
            app.CropMenu.MenuSelectedFcn = createCallbackFcn(app, @CropMenuSelected, true);
            app.CropMenu.Tooltip = {'Crop the selected region of interest'};
            app.CropMenu.Enable = 'off';
            app.CropMenu.Text = 'Crop';
            app.Toolbar = uitoolbar(app.CropImageUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Select a rectangular region of interest'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'getrect.png';
            app.UIAxes = uiaxes(app.CropImageUIFigure);
            % Show the figure after all components are created
            app.CropImageUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VideoCropper(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CropImageUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CropImageUIFigure)
        end
    end
end